classdef sistem_de_detectie_si_localizare_acustica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AchizitiadatelorTab            matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        DomeniultimpTab                matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        VizualizeazaButton             matlab.ui.control.Button
        DetectiapraguluienergeticTab   matlab.ui.container.Tab
        AfiseazaButton                 matlab.ui.control.Button
        UITable10                      matlab.ui.control.Table
        CrossCorelatiaTab              matlab.ui.container.Tab
        UIAxes9                        matlab.ui.control.UIAxes
        UIAxes10                       matlab.ui.control.UIAxes
        UIAxes11                       matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        STARTButton                    matlab.ui.control.Button
        RPATab                         matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        SpatiulStarilorTab             matlab.ui.container.Tab
        UIAxes34                       matlab.ui.control.UIAxes
        UIAxes35                       matlab.ui.control.UIAxes
        UIAxes36                       matlab.ui.control.UIAxes
        UIAxes37                       matlab.ui.control.UIAxes
        STARTButton_6                  matlab.ui.control.Button
        MatriceadistantelorTab         matlab.ui.container.Tab
        AfiseazamatriceadistantelorButton  matlab.ui.control.Button
        UIAxes38                       matlab.ui.control.UIAxes
        UIAxes39                       matlab.ui.control.UIAxes
        UIAxes40                       matlab.ui.control.UIAxes
        UIAxes41                       matlab.ui.control.UIAxes
        DiagramarecurentelorTab        matlab.ui.container.Tab
        UIAxes42                       matlab.ui.control.UIAxes
        UIAxes43                       matlab.ui.control.UIAxes
        UIAxes44                       matlab.ui.control.UIAxes
        UIAxes45                       matlab.ui.control.UIAxes
        CurbelededetectieTab           matlab.ui.container.Tab
        UIAxes46                       matlab.ui.control.UIAxes
        UIAxes47                       matlab.ui.control.UIAxes
        UIAxes48                       matlab.ui.control.UIAxes
        UIAxes49                       matlab.ui.control.UIAxes
        UITable6                       matlab.ui.control.Table
        TransformataWaveletTab         matlab.ui.container.Tab
        UITable3                       matlab.ui.control.Table
        UIAxes17                       matlab.ui.control.UIAxes
        UIAxes18                       matlab.ui.control.UIAxes
        UIAxes19                       matlab.ui.control.UIAxes
        UIAxes20                       matlab.ui.control.UIAxes
        STARTButton_3                  matlab.ui.control.Button
        SpectrogramaTab                matlab.ui.container.Tab
        UITable4                       matlab.ui.control.Table
        UIAxes24                       matlab.ui.control.UIAxes
        UIAxes25                       matlab.ui.control.UIAxes
        UIAxes26                       matlab.ui.control.UIAxes
        UIAxes27                       matlab.ui.control.UIAxes
        STARTButton_7                  matlab.ui.control.Button
        LocalizaresursaacusticaTab     matlab.ui.container.Tab
        UITable5                       matlab.ui.control.Table
        AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButton  matlab.ui.control.Button
        RezultateCrosscorelatiaButton  matlab.ui.control.Button
        RezultateRPAButton             matlab.ui.control.Button
        RezultateTransformataWaveletButton  matlab.ui.control.Button
        RezultateSpectrogramaButton    matlab.ui.control.Button
        UITable7                       matlab.ui.control.Table
        UITable8                       matlab.ui.control.Table
        UITable9                       matlab.ui.control.Table
        UITable12                      matlab.ui.control.Table
        DetectiapraguluienergeticButton  matlab.ui.control.Button
    end

    
    properties (Access= public)
        s1;
        s2;
        s3;
        s4;
        x1;x2;x3;x4;y1;y2;y3;y4;z1;z2;z3;z4;
        Fs;
        t12cr; t13cr; t14cr;
        tdoa_sp12;tdoa_sp13;tdoa_sp14;
        tdoa12_wave; tdoa13_wave; tdoa14_wave;
        tdoa12_rpa; tdoa13_rpa; tdoa14_rpa;
        tdoa12_dpe;  tdoa13_dpe;  tdoa14_dpe;
        
    end
    methods (Access = public)
        function startupFcn(app,s1,s2,s3,s4)
            
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            
            app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            
            
        end
        
        
    end


    methods (Access = private)

        % Callback function
        function VizualizeazaButton_2Pushed(app, event)
            
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            N=length(app.s1);
            fftsemnal=fft( app.s1);  SEMNF=abs(fftsemnal/N);   SEMNF11=2*SEMNF(1:N/2+1);
            fftsemnal1=fft( app.s2);SEMNF1=abs(fftsemnal1/N); SEMNF12=2*SEMNF1(1:N/2+1);
            fftsemnal2=fft( app.s3);SEMNF2=abs(fftsemnal2/N); SEMNF13=2*SEMNF2(1:N/2+1);
            fftsemnal3=fft( app.s4);SEMNF3=abs(fftsemnal3/N); SEMNF14=2*SEMNF3(1:N/2+1);
            plot(app.UIAxes5,SEMNF11);
            plot(app.UIAxes7,SEMNF12);
            plot(app.UIAxes6,SEMNF13);
            plot(app.UIAxes8,SEMNF14);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);


            t3=0:1/app.Fs:6;
            [acor,lag] = xcorr( app.s1, app.s2);
            [~,I] = max(abs(acor));
            timeDiff = lag(I)
            
            plot(app.UIAxes9,lag,acor);
            a=finddelay( app.s1, app.s2);
            app.t12cr=1/app.Fs*a ;
            
            [acor1,lag1] = xcorr( app.s1, app.s3);
            [~,I1] = max(abs(acor1));
            timeDiff1 = lag(I1)   ;
            plot(app.UIAxes11,lag1,acor1)
            a1=finddelay( app.s1, app.s3);
            app.t13cr=1/ app.Fs*a1 ;
            
            [acor2,lag2] = xcorr(app.s1,app.s4);
            [~,I2] = max(abs(acor2));
            timeDiff2 = lag(I2)        ;
            plot(app.UIAxes10,lag2,acor2);
            a2=finddelay( app.s1, app.s4);
            app.t14cr=1/ app.Fs*a2 ;
            
            date= {app.t12cr,app.t13cr,app.t14cr};
            app.UITable.Data = date;
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            date= {app.t12cr,app.t13cr,app.t14cr};
            app.UITable.Data = date;
        end

        % Callback function
        function UITable2CellEdit(app, event)
            date1= { app.tdoa12_rpa, app.tdoa13_rpa, app.tdoa14_rpa};
            app.UITable2.Data = date1;
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            date2 ={ app.t12wave, app.t13wave,app.t14wave};
            app.UITable3.Data = date2;
        end

        % Cell edit callback: UITable4
        function UITable4CellEdit(app, event)
            date3= {t12sp, t13sp, t14sp};
            app.UITable4.Data=date3;
        end

        % Callback function
        function STARTButton_2Pushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);


        end

        % Button pushed function: STARTButton_3
        function STARTButton_3Pushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            a0 = 2^(0.015625);
            scales = a0.^(1*64:4*64);
            [cfs1,sc1] = cwt( app.s1,scales,'haar',mrdivide(1,app.Fs),'scal');
            [cfs2,sc2] = cwt(app.s2,scales,'haar',mrdivide(1,app.Fs),'scal');
            [cfs3,sc3] = cwt(app.s3,scales,'haar',mrdivide(1,app.Fs),'scal');
            [cfs4,sc4] = cwt(app.s4,scales,'haar',mrdivide(1,app.Fs),'scal');
            plot(app.UIAxes17,sum(abs(cfs1)));
            plot(app.UIAxes18,sum(abs(cfs2)));
            plot(app.UIAxes19,sum(abs(cfs3)));
            plot(app.UIAxes20,sum(abs(cfs4)));
            [val1,tt1]=max(sum(abs(cfs1)));
            [val2,tt2]=max(sum(abs(cfs2)));
            [val3,tt3]=max(sum(abs(cfs3)));
            [val4,tt4]=max(sum(abs(cfs4)));
            prag=0.4;
            
            I1=find(sum(abs(cfs1))>prag*val1,1);
            I2=find(sum(abs(cfs2))>prag*val2,1);
            I3=find(sum(abs(cfs3))>prag*val3,1);
            I4=find(sum(abs(cfs4))>prag*val4,1);
            
            
            n1=max(I1)/app.Fs;
            n2=max(I2)/app.Fs;
            n3=max(I3)/app.Fs;
            n4=max(I4)/app.Fs;
            
            app.tdoa12_wave=n1-n2;
            app.tdoa13_wave=n1-n3;
            app.tdoa14_wave=n1-n4;
            
            date2 ={ app.tdoa12_wave,app.tdoa13_wave,app.tdoa14_wave};
            app.UITable3.Data = date2;
            figure
            
            cwt(app.s1,scales,'haar',1/app.Fs,'scal');
            xlabel ('Esantioane'), ylabel('Scara'); title('Scalograma semnalului receptionat 1');
            figure
            cwt(app.s2,scales,'haar',1/app.Fs,'scal');
            xlabel ('Esantioane'), ylabel('Scara'); title('Scalograma semnalului receptionat 2');
            figure
            cwt(app.s3,scales,'haar',1/app.Fs,'scal');
            xlabel ('Esantioane'), ylabel('Scara'); title('Scalograma semnalului receptionat 3');
            figure
            cwt(app.s4,scales,'haar',1/app.Fs,'scal');
            xlabel ('Esantioane'), ylabel('Scara'); title('Scalograma semnalului receptionat 4');
            
        end

        % Button pushed function: STARTButton_7
        function STARTButton_4Pushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            
            [S1] = spectrogram(app.s1,32,16,512,app.Fs,'yaxis');
            [S2] = spectrogram(app.s2,32,16,512,app.Fs,'yaxis');
            [S3] = spectrogram(app.s3,32,16,512,app.Fs,'yaxis');
            [S4] = spectrogram(app.s4,32,16,512,app.Fs,'yaxis');
            
            plot(app.UIAxes24,sum(abs(S1)));
            plot(app.UIAxes25,sum(abs(S2)));
            plot(app.UIAxes26,sum(abs(S3)));
            plot(app.UIAxes27,sum(abs(S4)));
            
            nr=length(app.s1)/length(S1);
            [val1,tt1]=max(sum(abs(S1)));
            [val2,tt2]=max(sum(abs(S2)));
            [val3,tt3]=max(sum(abs(S3)));
            [val4,tt4]=max(sum(abs(S4)));
            
            prag=0.4;
            I1=find(sum(abs(S1))>prag*val1,1);
            I2=find(sum(abs(S2))>prag*val2,1);
            I3=find(sum(abs(S3))>prag*val3,1);
            I4=find(sum(abs(S4))>prag*val4,1);
            
            
            n1=nr*I1/app.Fs;
            n2=nr*I2/app.Fs;
            n3=nr*I3/app.Fs;
            n4=nr*I4/app.Fs;
            
            app.tdoa_sp12=n1-n2;
            app.tdoa_sp13=n1-n3;
            app.tdoa_sp14=n1-n4;
            date3= {app.tdoa_sp12,app.tdoa_sp13,app.tdoa_sp14};
            app.UITable4.Data=date3;
            
            figure
            subplot(2,2,1)
            spectrogram(app.s1,128,50,128,app.Fs,'yaxis'); title('Spectrograma primului semnal');
            subplot(2,2,2)
            spectrogram(app.s2,128,50,128,app.Fs,'yaxis'); title('Spectrograma semnalului 2');
            subplot(2,2,3)
            spectrogram(app.s3,128,50,128,app.Fs,'yaxis'); title('Spectrograma semnalului 3');
            subplot(2,2,4)
            spectrogram(app.s4,128,50,128,app.Fs,'yaxis'); title('Spectrograma semnalului 4');
            
            
        end

        % Button pushed function: 
        % AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButton
        function AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButtonPushed(app, event)
            
            app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            
            x=[app.x1,app.x2,app.x3,app.x4];
            y=[app.y1,app.y2,app.y3,app.y4];
            z=[app.z1,app.z2,app.z3,app.z4];
            v=343;
            M=4;
            A=zeros(4,1); B=zeros(4,1); C=zeros(4,1); D=zeros(4,1);
            t12=app.tdoa12_dpe;
            t13=app.tdoa13_dpe;
            t14=app.tdoa14_dpe;
            if t12 == 0 | t13 == 0 | t14 == 0
                
                xs=0;ys=0;zs=0;
            else
                t122=t12;
                t133=t13;
                t144=t14;
            end
            tdoa_p=[0,t122,t133,t144];
            for i=3:4
                A(i) = (1/(v*tdoa_p(i)))*(-2*app.x1+2*x(i)) - (1/(v*tdoa_p(2)))*(-2*app.x1+2*app.x2);
                B(i) = (1/(v*tdoa_p(i)))*(-2*app.y1+2*y(i)) - (1/(v*tdoa_p(2)))*(-2*app.y1+2*app.y2);
                C(i) = (1/(v*tdoa_p(i)))*(-2*app.z1+2*z(i)) - (1/(v*tdoa_p(2)))*(-2*app.z1+2*app.z2);
                Sum1 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(x(i)^2)-(y(i)^2)-(z(i)^2);
                Sum2 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(app.x2^2)-(app.y2^2)-(app.z2^2);
                Dm(i) = v*(tdoa_p(i) - tdoa_p(2)) + (1/(v*tdoa_p(i)))*Sum1 - (1/(v*tdoa_p(2)))*Sum2;
            end
            M = zeros(4,3);
            D = zeros(4,1);
            for i=1:4
                M(i,1) = A(i);
                M(i,2) = B(i);
                M(i,3) = C(i);
                D(i) = Dm(i);
            end
            
            M = M(3:4,:);
            D = D(3:4);
            
            
            D = D.*-1;
            
            Minv = pinv(M);
            T = Minv*(D);
            xs = T(1)
            ys = T(2)
            zs = T(3)
            
            X=[xs,app.x1]; X1=[xs,app.x2];X2=[xs,app.x3];X3=[xs,app.x4];
            Y=[ys,app.y1]; Y1=[ys,app.y2];Y2=[ys,app.y3];Y3=[ys,app.y4];
            Z=[zs,app.z1];Z1=[zs,app.z2];Z2=[zs,app.z3];Z3=[zs,app.z4];


            figure
            title('Pozitionarea microfoanelor si a sursei de sunet' );
            scatter3(xs,ys,zs,'red','s','filled');
            hold on;
            scatter3(app.x1,app.y1,app.z1,'blue','h','filled');
            hold on;
            scatter3(app.x2,app.y2,app.z2,'green','h','filled');
            hold on;
            scatter3(app.x3,app.y3,app.z3,'yellow','h','filled');
            hold on;
            scatter3(app.x4,app.y4,app.z4,'black','h','filled');
            hold on;
            xlabel('Axa x');ylabel('Axa y'); zlabel('Axa z');
            xlim([-1.5 1.5]);ylim([-1.5 1.5]);zlim([-1.5 1.5]);
            legend('"Sursa de emisie estimata"', '"Microfonul 1"', '"Microfonul 2"','"Microfonul 3"','"Microfonul 4"');
            view(30,30);
            plot3(X,Y,Z);hold on;
            plot3(X1,Y1,Z1); hold on;
            plot3(X2,Y2,Z2); hold on;
            plot3(X3,Y3,Z3);
            grid on; hold off;
            
            
        end

        % Button pushed function: VizualizeazaButton
        function VizualizeazaButtonPushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            
            plot(app.UIAxes, app.s1);
            plot(app.UIAxes3,app.s2);
            plot(app.UIAxes2,app.s3);
            plot(app.UIAxes4,app.s4);
            
            
        end

        % Button pushed function: STARTButton_6
        function STARTButton_6Pushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            tr=0:1/app.Fs:length(app.s1);
            N = length(app.s1);
            dim=3;
            tau=1;
            
            T=N-(dim-1)*tau;
            
            Y=zeros(T,dim);
            Y1=zeros(T,dim);
            Y2=zeros(T,dim);
            Y3=zeros(T,dim);
            for i=1:T
                Y(i,:)= app.s1(i+(dim-1)*tau-sort((0:dim-1),'descend')*tau)';
                Y1(i,:)= app.s2(i+(dim-1)*tau-sort((0:dim-1),'descend')*tau)';
                Y2(i,:)= app.s3(i+(dim-1)*tau-sort((0:dim-1),'descend')*tau)';
                Y3(i,:)= app.s4(i+(dim-1)*tau-sort((0:dim-1),'descend')*tau)';
            end
            
            sizeY=size(Y,2);
            
            if nargout == 0
                if sizeY == 2
                    plot(app.UIAxes34,Y(:,1),Y(:,2));
                    plot(app.UIAxes35,Y1(:,1),Y1(:,2));
                    plot(app.UIAxes36,Y2(:,1),Y2(:,2));
                    plot(app.UIAxes37,Y3(:,1),Y3(:,2));
                    
                else
                    plot3(app.UIAxes34,Y(:,1),Y(:,2),Y(:,3));
                    plot3(app.UIAxes35,Y1(:,1),Y1(:,2),Y1(:,3));
                    plot3(app.UIAxes36,Y2(:,1),Y2(:,2),Y2(:,3));
                    plot3(app.UIAxes37,Y3(:,1),Y3(:,2),Y3(:,3));
                    
                end
            end
            
        end

        % Button pushed function: AfiseazamatriceadistantelorButton
        function AfiseazamatriceadistantelorButtonPushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            tr=0:1/app.Fs:length(app.s1);
            N = length(app.s1);
            N1 = length(app.s2);
            N2= length(app.s3);
            N3 = length(app.s4);
            m=3;
            t=1;
            r1=0.5;
            index=0;
            tr=0:1/app.Fs:length(app.s1);
            P=app.s1';
            P1=app.s2';
            P2=app.s3';
            P3=app.s4';
            r = r1*std(P);
            r2 = r1*std(P1);
            r3 = r1*std(P2);
            r4 = r1*std(P3);
            
            X=zeros(N-(m-1)*t,m);
            for k=1:(N-(m-1)*t)
                PP=[];
                for i=1:m
                    PP=[PP P(1,k-t+i*t)];
                end
                X(k,:)=PP;
            end
            N1=length(X);
            DD=zeros(N1,N1);
            if index == 0
                r = r*r;
                for k1=1:N1
                    for k2=k1+1:N1
                        DD(k1,k2) = sum( (X(k1,:) - X(k2,:)).^2 );
                    end
                end
                DD=DD+DD';
            end
            RP=zeros(N1,N1);
            for i=1:N1
                for j=i+1:N1
                    if DD(i,j) <= r
                        RP(i,j)=1;
                        RP(j,i)=1;
                    end
                end
            end
            RP = RP +eye(N1);


            X1=zeros(N1-(m-1)*t,m);
            for k=1:(N1-(m-1)*t)
                PP1=[];
                for i=1:m
                    PP1=[PP1 P1(1,k-t+i*t)];
                end
                X1(k,:)=PP1;
            end
            N11=length(X1);
            DD1=zeros(N11,N11);
            if index == 0
                r2 = r2*r2;
                for k1=1:N11
                    for k2=k1+1:N11
                        DD1(k1,k2) = sum( (X1(k1,:) - X1(k2,:)).^2 );
                    end
                end
                DD1=DD1+DD1';
            end
            RP1=zeros(N11,N11);
            for i=1:N11
                for j=i+1:N11
                    if DD1(i,j) <= r2
                        RP1(i,j)=1;
                        RP1(j,i)=1;
                    end
                end
            end
            RP1 = RP1 +eye(N11);
            
            X2=zeros(N2-(m-1)*t,m);
            for k=1:(N2-(m-1)*t)
                PP2=[];
                for i=1:m
                    PP2=[PP2 P2(1,k-t+i*t)];
                end
                X2(k,:)=PP2;
            end
            N12=length(X2);
            DD2=zeros(N12,N12);
            if index == 0
                r3 = r3*r3;
                for k1=1:N12
                    for k2=k1+1:N12
                        DD2(k1,k2) = sum( (X2(k1,:) - X2(k2,:)).^2 );
                    end
                end
                DD2=DD2+DD2';
            end
            RP2=zeros(N12,N12);
            for i=1:N12
                for j=i+1:N12
                    if DD2(i,j) <= r3
                        RP2(i,j)=1;
                        RP2(j,i)=1;
                    end
                end
            end
            RP2 = RP2 +eye(N12);
            
            X3=zeros(N3-(m-1)*t,m);
            for k=1:(N3-(m-1)*t)
                PP3=[];
                for i=1:m
                    PP3=[PP3 P3(1,k-t+i*t)];
                end
                X3(k,:)=PP3;
            end
            N33=length(X3);
            DD3=zeros(N33,N33);
            if index == 0
                r4 = r4*r4;
                for k1=1:N33
                    for k2=k1+1:N33
                        DD3(k1,k2) = sum( (X3(k1,:) - X3(k2,:)).^2 );
                    end
                end
                DD3=DD3+DD3';
            end
            RP3=zeros(N33,N33);
            for i=1:N33
                for j=i+1:N33
                    if DD3(i,j) <= r4
                        RP3(i,j)=1;
                        RP3(j,i)=1;
                    end
                end
            end
            RP3 = RP3 +eye(N33);
            
            imagesc(app.UIAxes38,tr,tr,DD);
            imagesc(app.UIAxes39,tr,tr,DD1);
            imagesc(app.UIAxes40,tr,tr,DD2);
            imagesc(app.UIAxes41,tr,tr,DD3);
            
            imagesc(app.UIAxes42,tr,tr,RP);
            imagesc(app.UIAxes43,tr,tr,RP1);
            imagesc(app.UIAxes44,tr,tr,RP2);
            imagesc(app.UIAxes45,tr,tr,RP3);
            
            
            [aa,bb]=size(DD1);
            C1=zeros(1,bb);C2=zeros(1,bb);
            C3=zeros(1,bb);C4=zeros(1,bb);
            
            for j=1:bb
                C1(j) = sum(DD(:,j));
                
                C2(j) = sum(DD1(:,j));
                
                C3(j) = sum(DD2(:,j));
                
                C4(j) = sum(DD3(:,j));
                
                
            end
            
            
            [val1,tt1]=max(C1);
            [val2,tt2]=max(C2);
            [val3,tt3]=max(C3);
            [val4,tt4]=max(C4);
            prag=0.4;
            
            I1=find(C1>prag*val1,1,'first');
            I2=find(C2>prag*val2,1,'first');
            I3=find(C3>prag*val3,1,'first');
            I4=find(C4>prag*val4,1,'first');
            
            
            n1=I1/app.Fs;
            n2=I2/app.Fs;
            n3=I3/app.Fs;
            n4=I4/app.Fs;
            
            app.tdoa12_rpa=n1-n2;
            app.tdoa13_rpa=n1-n3;
            app.tdoa14_rpa=n1-n4;
            date1= { app.tdoa12_rpa, app.tdoa13_rpa, app.tdoa14_rpa};
            app.UITable6.Data = date1;
            
            
            plot(app.UIAxes46, C1);
            plot(app.UIAxes47, C2);
            
            plot(app.UIAxes48, C3);
            
            plot(app.UIAxes49, C4);
            
            
        end

        % Button pushed function: RezultateCrosscorelatiaButton
        function RezultateCrosscorelatiaButtonPushed(app, event)
            app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            x=[app.x1,app.x2,app.x3,app.x4];
            y=[app.y1,app.y2,app.y3,app.y4];
            z=[app.z1,app.z2,app.z3,app.z4];
            v=343;
            M=4;
            A=zeros(4,1); B=zeros(4,1); C=zeros(4,1); D=zeros(4,1);
            t12=app.t12cr;
            t13=app.t13cr;
            t14=app.t14cr;
            if t12 == 0 | t13 == 0 | t14 == 0
                
                xs=0;ys=0;zs=0;
            else
                t122=t12;
                t133=t13;
                t144=t14;
            end
            tdoa_p=[0,t122,t133,t144];
            for i=3:4
                A(i) = (1/(v*tdoa_p(i)))*(-2*app.x1+2*x(i)) - (1/(v*tdoa_p(2)))*(-2*app.x1+2*app.x2);
                B(i) = (1/(v*tdoa_p(i)))*(-2*app.y1+2*y(i)) - (1/(v*tdoa_p(2)))*(-2*app.y1+2*app.y2);
                C(i) = (1/(v*tdoa_p(i)))*(-2*app.z1+2*z(i)) - (1/(v*tdoa_p(2)))*(-2*app.z1+2*app.z2);
                Sum1 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(x(i)^2)-(y(i)^2)-(z(i)^2);
                Sum2 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(app.x2^2)-(app.y2^2)-(app.z2^2);
                Dm(i) = v*(tdoa_p(i) - tdoa_p(2)) + (1/(v*tdoa_p(i)))*Sum1 - (1/(v*tdoa_p(2)))*Sum2;
            end
            M = zeros(4,3);
            D = zeros(4,1);
            for i=1:4
                M(i,1) = A(i);
                M(i,2) = B(i);
                M(i,3) = C(i);
                D(i) = Dm(i);
            end
            
            M = M(3:4,:);
            D = D(3:4);
            
            
            D = D.*-1;
            
            Minv = pinv(M);
            T = Minv*(D);
            xs = T(1)
            ys = T(2)
            zs = T(3)
            
            X=[xs,app.x1]; X1=[xs,app.x2];X2=[xs,app.x3];X3=[xs,app.x4];
            Y=[ys,app.y1]; Y1=[ys,app.y2];Y2=[ys,app.y3];Y3=[ys,app.y4];
            Z=[zs,app.z1];Z1=[zs,app.z2];Z2=[zs,app.z3];Z3=[zs,app.z4];
            date1= {xs, ys, zs};
            app.UITable5.Data = date1;
            
            
        end

        % Button pushed function: RezultateRPAButton
        function RezultateRPAButtonPushed(app, event)
            app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            
            x=[app.x1,app.x2,app.x3,app.x4];
            y=[app.y1,app.y2,app.y3,app.y4];
            z=[app.z1,app.z2,app.z3,app.z4];
            v=343;
            M=4;
            A=zeros(4,1); B=zeros(4,1); C=zeros(4,1); D=zeros(4,1);
            t12=app.tdoa12_rpa;
            t13=app.tdoa13_rpa;
            t14=app.tdoa14_rpa;
            if t12 == 0 | t13 == 0 | t14 == 0
                
                xs=0;ys=0;zs=0;
            else
                t122=t12;
                t133=t13;
                t144=t14;
            end
            tdoa_p=[0,t122,t133,t144];
            for i=3:4
                A(i) = (1/(v*tdoa_p(i)))*(-2*app.x1+2*x(i)) - (1/(v*tdoa_p(2)))*(-2*app.x1+2*app.x2);
                B(i) = (1/(v*tdoa_p(i)))*(-2*app.y1+2*y(i)) - (1/(v*tdoa_p(2)))*(-2*app.y1+2*app.y2);
                C(i) = (1/(v*tdoa_p(i)))*(-2*app.z1+2*z(i)) - (1/(v*tdoa_p(2)))*(-2*app.z1+2*app.z2);
                Sum1 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(x(i)^2)-(y(i)^2)-(z(i)^2);
                Sum2 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(app.x2^2)-(app.y2^2)-(app.z2^2);
                Dm(i) = v*(tdoa_p(i) - tdoa_p(2)) + (1/(v*tdoa_p(i)))*Sum1 - (1/(v*tdoa_p(2)))*Sum2;
            end
            M = zeros(4,3);
            D = zeros(4,1);
            for i=1:4
                M(i,1) = A(i);
                M(i,2) = B(i);
                M(i,3) = C(i);
                D(i) = Dm(i);
            end
            
            M = M(3:4,:);
            D = D(3:4);
            
            
            D = D.*-1;
            
            Minv = pinv(M);
            T = Minv*(D);
            xs = T(1)
            ys = T(2)
            zs = T(3)
            
            X=[xs,app.x1]; X1=[xs,app.x2];X2=[xs,app.x3];X3=[xs,app.x4];
            Y=[ys,app.y1]; Y1=[ys,app.y2];Y2=[ys,app.y3];Y3=[ys,app.y4];
            Z=[zs,app.z1];Z1=[zs,app.z2];Z2=[zs,app.z3];Z3=[zs,app.z4];
            date1= {  xs, ys, zs};
            app.UITable7.Data = date1;
        end

        % Button pushed function: RezultateTransformataWaveletButton
        function RezultateTransformataWaveletButtonPushed(app, event)
            app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            x=[app.x1,app.x2,app.x3,app.x4];
            y=[app.y1,app.y2,app.y3,app.y4];
            z=[app.z1,app.z2,app.z3,app.z4];
            v=343;
            M=4;
            A=zeros(4,1); B=zeros(4,1); C=zeros(4,1); D=zeros(4,1);
            t12=app.tdoa12_wave;
            t13=app.tdoa13_wave;
            t14=app.tdoa14_wave;
            if t12 == 0 | t13 == 0 | t14 == 0
                
                xs=0;ys=0;zs=0;
            else
                t122=t12;
                t133=t13;
                t144=t14;
            end
            tdoa_p=[0,t122,t133,t144];
            for i=3:4
                A(i) = (1/(v*tdoa_p(i)))*(-2*app.x1+2*x(i)) - (1/(v*tdoa_p(2)))*(-2*app.x1+2*app.x2);
                B(i) = (1/(v*tdoa_p(i)))*(-2*app.y1+2*y(i)) - (1/(v*tdoa_p(2)))*(-2*app.y1+2*app.y2);
                C(i) = (1/(v*tdoa_p(i)))*(-2*app.z1+2*z(i)) - (1/(v*tdoa_p(2)))*(-2*app.z1+2*app.z2);
                Sum1 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(x(i)^2)-(y(i)^2)-(z(i)^2);
                Sum2 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(app.x2^2)-(app.y2^2)-(app.z2^2);
                Dm(i) = v*(tdoa_p(i) - tdoa_p(2)) + (1/(v*tdoa_p(i)))*Sum1 - (1/(v*tdoa_p(2)))*Sum2;
            end
            M = zeros(4,3);
            D = zeros(4,1);
            for i=1:4
                M(i,1) = A(i);
                M(i,2) = B(i);
                M(i,3) = C(i);
                D(i) = Dm(i);
            end
            
            M = M(3:4,:);
            D = D(3:4);
            
            
            D = D.*-1;
            
            Minv = pinv(M);
            T = Minv*(D);
            xs = T(1)
            ys = T(2)
            zs = T(3)
            
            X=[xs,app.x1]; X1=[xs,app.x2];X2=[xs,app.x3];X3=[xs,app.x4];
            Y=[ys,app.y1]; Y1=[ys,app.y2];Y2=[ys,app.y3];Y3=[ys,app.y4];
            Z=[zs,app.z1];Z1=[zs,app.z2];Z2=[zs,app.z3];Z3=[zs,app.z4];
            date1= {xs, ys, zs};
            app.UITable8.Data = date1;
        end

        % Button pushed function: RezultateSpectrogramaButton
        function RezultateSpectrogramaButtonPushed(app, event)
            app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            
            x=[app.x1,app.x2,app.x3,app.x4];
            y=[app.y1,app.y2,app.y3,app.y4];
            z=[app.z1,app.z2,app.z3,app.z4];
            v=343;
            M=4;
            A=zeros(4,1); B=zeros(4,1); C=zeros(4,1); D=zeros(4,1);
            t12=app.tdoa_sp12;
            t13=app.tdoa_sp13;
            t14=app.tdoa_sp14;
            if t12 == 0 | t13 == 0 | t14 == 0
                
                xs=0;ys=0;zs=0;
            else
                t122=t12;
                t133=t13;
                t144=t14;
            end
            tdoa_p=[0,t122,t133,t144];
            for i=3:4
                A(i) = (1/(v*tdoa_p(i)))*(-2*app.x1+2*x(i)) - (1/(v*tdoa_p(2)))*(-2*app.x1+2*app.x2);
                B(i) = (1/(v*tdoa_p(i)))*(-2*app.y1+2*y(i)) - (1/(v*tdoa_p(2)))*(-2*app.y1+2*app.y2);
                C(i) = (1/(v*tdoa_p(i)))*(-2*app.z1+2*z(i)) - (1/(v*tdoa_p(2)))*(-2*app.z1+2*app.z2);
                Sum1 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(x(i)^2)-(y(i)^2)-(z(i)^2);
                Sum2 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(app.x2^2)-(app.y2^2)-(app.z2^2);
                Dm(i) = v*(tdoa_p(i) - tdoa_p(2)) + (1/(v*tdoa_p(i)))*Sum1 - (1/(v*tdoa_p(2)))*Sum2;
            end
            M = zeros(4,3);
            D = zeros(4,1);
            for i=1:4
                M(i,1) = A(i);
                M(i,2) = B(i);
                M(i,3) = C(i);
                D(i) = Dm(i);
            end
            
            M = M(3:4,:);
            D = D(3:4);
            
            
            D = D.*-1;
            
            Minv = pinv(M);
            T = Minv*(D);
            xs = T(1)
            ys = T(2)
            zs = T(3)
            
            X=[xs,app.x1]; X1=[xs,app.x2];X2=[xs,app.x3];X3=[xs,app.x4];
            Y=[ys,app.y1]; Y1=[ys,app.y2];Y2=[ys,app.y3];Y3=[ys,app.y4];
            Z=[zs,app.z1];Z1=[zs,app.z2];Z2=[zs,app.z3];Z3=[zs,app.z4];
            date1= { xs, ys, zs};
            app.UITable9.Data = date1;
        end

        % Button pushed function: AfiseazaButton
        function AfiseazaButtonPushed(app, event)
            fileID=fopen('s1.txt', 'r');
            app.s1=fscanf(fileID, '%f');
            fclose(fileID);
            
            
            fileID=fopen('s2.txt', 'r');
            app.s2=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s3.txt', 'r')
            app.s3=fscanf(fileID, '%f');
            fclose(fileID);
            
            fileID=fopen('s4.txt', 'r');
            app.s4=fscanf(fileID, '%f');
            fclose(fileID);
            fileID=fopen('fs.txt', 'r');
            app.Fs=fscanf(fileID, '%f');
            fclose(fileID);
            t=0:1/app.Fs:length(app.s1);
            
            level=0.35;
            n1=find(abs(app.s1(5:end)-1.4)>level,1,'first');
            n2=find(abs(app.s2(5:end)-1.4)>level,1,'first');
            n3=find(abs(app.s3(5:end)-1.4)>level,1,'first');
            n4=find(abs(app.s4(5:end)-1.4)>level,1,'first');
            app.tdoa12_dpe=(n1-n2)/app.Fs;
            app.tdoa13_dpe=(n1-n3)/app.Fs;
            app.tdoa14_dpe=(n1-n4)/app.Fs;
            
            data=[  app.tdoa12_dpe,  app.tdoa13_dpe,  app.tdoa14_dpe];
            app.UITable10.Data=data;
            
              app.x1=0;       app.y1=0;       app.z1=0;
            app.x2=1;     app.y2=0.65;    app.z2=0;
            app.x3=-0.95;    app.y3=0.30;    app.z3=0.25;
            app.x4=0.2;    app.y4=0.85;    app.z4=0.52;
            
            x=[app.x1,app.x2,app.x3,app.x4];
            y=[app.y1,app.y2,app.y3,app.y4];
            z=[app.z1,app.z2,app.z3,app.z4];
            v=343;
            M=4;
            A=zeros(4,1); B=zeros(4,1); C=zeros(4,1); D=zeros(4,1);
            t12=app.tdoa12_dpe;
            t13=app.tdoa13_dpe;
            t14=app.tdoa14_dpe;
            if t12 == 0 | t13 == 0 | t14 == 0
                
                xs=0;ys=0;zs=0;
            else
                t122=t12;
                t133=t13;
                t144=t14;
            end
            tdoa_p=[0,t122,t133,t144];
            for i=3:4
                A(i) = (1/(v*tdoa_p(i)))*(-2*app.x1+2*x(i)) - (1/(v*tdoa_p(2)))*(-2*app.x1+2*app.x2);
                B(i) = (1/(v*tdoa_p(i)))*(-2*app.y1+2*y(i)) - (1/(v*tdoa_p(2)))*(-2*app.y1+2*app.y2);
                C(i) = (1/(v*tdoa_p(i)))*(-2*app.z1+2*z(i)) - (1/(v*tdoa_p(2)))*(-2*app.z1+2*app.z2);
                Sum1 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(x(i)^2)-(y(i)^2)-(z(i)^2);
                Sum2 = (app.x1^2)+(app.y1^2)+(app.z1^2)-(app.x2^2)-(app.y2^2)-(app.z2^2);
                Dm(i) = v*(tdoa_p(i) - tdoa_p(2)) + (1/(v*tdoa_p(i)))*Sum1 - (1/(v*tdoa_p(2)))*Sum2;
            end
            M = zeros(4,3);
            D = zeros(4,1);
            for i=1:4
                M(i,1) = A(i);
                M(i,2) = B(i);
                M(i,3) = C(i);
                D(i) = Dm(i);
            end
            
            M = M(3:4,:);
            D = D(3:4);
            
            
            D = D.*-1;
            
            Minv = pinv(M);
            T = Minv*(D);
            xs = T(1)
            ys = T(2)
            zs = T(3)
            
            X=[xs,app.x1]; X1=[xs,app.x2];X2=[xs,app.x3];X3=[xs,app.x4];
            Y=[ys,app.y1]; Y1=[ys,app.y2];Y2=[ys,app.y3];Y3=[ys,app.y4];
            Z=[zs,app.z1];Z1=[zs,app.z2];Z2=[zs,app.z3];Z3=[zs,app.z4];
            date1= { xs, ys, zs};
            app.UITable12.Data = date1;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AchizitiadatelorTab = uitab(app.TabGroup);
            app.AchizitiadatelorTab.Title = 'Achizitia datelor';
            app.TabGroup2 = uitabgroup(app.AchizitiadatelorTab);
            app.DomeniultimpTab = uitab(app.TabGroup2);
            app.DomeniultimpTab.Title = 'Domeniul timp';
            app.UIAxes = uiaxes(app.DomeniultimpTab);
            title(app.UIAxes, 'Microfonul 1')
            xlabel(app.UIAxes, 'Esantioane')
            ylabel(app.UIAxes, 'Amplitudine [V]')
            app.UIAxes2 = uiaxes(app.DomeniultimpTab);
            title(app.UIAxes2, 'Microfonul 3')
            xlabel(app.UIAxes2, 'Esantioane')
            ylabel(app.UIAxes2, 'Amplitudine [V]')
            app.UIAxes3 = uiaxes(app.DomeniultimpTab);
            title(app.UIAxes3, 'Microfonul 2')
            xlabel(app.UIAxes3, 'Esantioane')
            ylabel(app.UIAxes3, 'Amplitudine [V]')
            app.UIAxes4 = uiaxes(app.DomeniultimpTab);
            title(app.UIAxes4, 'Microfonul 4')
            xlabel(app.UIAxes4, 'Esantioane')
            ylabel(app.UIAxes4, 'Amplitudine [V]')
            app.VizualizeazaButton = uibutton(app.DomeniultimpTab, 'push');
            app.VizualizeazaButton.ButtonPushedFcn = createCallbackFcn(app, @VizualizeazaButtonPushed, true);
            app.VizualizeazaButton.Text = 'Vizualizeaza';
            app.DetectiapraguluienergeticTab = uitab(app.TabGroup2);
            app.DetectiapraguluienergeticTab.Title = 'Detectia pragului energetic';
            app.AfiseazaButton = uibutton(app.DetectiapraguluienergeticTab, 'push');
            app.AfiseazaButton.ButtonPushedFcn = createCallbackFcn(app, @AfiseazaButtonPushed, true);
            app.AfiseazaButton.Text = 'Afiseaza';
            app.UITable10 = uitable(app.DetectiapraguluienergeticTab);
            app.UITable10.ColumnName = {'TDOA 12'; 'TDOA 13'; 'TDOA 14'};
            app.UITable10.RowName = {};
            app.CrossCorelatiaTab = uitab(app.TabGroup);
            app.CrossCorelatiaTab.Title = 'Cross-Corelatia';
            app.UIAxes9 = uiaxes(app.CrossCorelatiaTab);
            title(app.UIAxes9, 'Corelatia semnalului 1 si 2')
            xlabel(app.UIAxes9, 'Esantioane')
            ylabel(app.UIAxes9, 'Magnitudine')
            app.UIAxes10 = uiaxes(app.CrossCorelatiaTab);
            title(app.UIAxes10, 'Corelatia semnalului 1 si 4')
            xlabel(app.UIAxes10, 'Esantioane')
            ylabel(app.UIAxes10, 'Magnitudine')
            app.UIAxes11 = uiaxes(app.CrossCorelatiaTab);
            title(app.UIAxes11, 'Corelatia semnalului 1 si 3')
            xlabel(app.UIAxes11, 'Esantioane')
            ylabel(app.UIAxes11, 'Magnitudine')
            app.UITable = uitable(app.CrossCorelatiaTab);
            app.UITable.ColumnName = {'TDOA12  (sec)'; 'TDOA13  (sec)'; 'TDOA14  (sec)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.STARTButton = uibutton(app.CrossCorelatiaTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RPATab = uitab(app.TabGroup);
            app.RPATab.Title = 'RPA';
            app.TabGroup4 = uitabgroup(app.RPATab);
            app.SpatiulStarilorTab = uitab(app.TabGroup4);
            app.SpatiulStarilorTab.Title = 'Spatiul Starilor';
            app.UIAxes34 = uiaxes(app.SpatiulStarilorTab);
            title(app.UIAxes34, 'Spatiul starilor 1')
            xlabel(app.UIAxes34, 'X')
            ylabel(app.UIAxes34, 'Y')
            app.UIAxes35 = uiaxes(app.SpatiulStarilorTab);
            title(app.UIAxes35, 'Spatiul starilor 3')
            xlabel(app.UIAxes35, 'X')
            ylabel(app.UIAxes35, 'Y')
            app.UIAxes36 = uiaxes(app.SpatiulStarilorTab);
            title(app.UIAxes36, 'Spatiul starilor 2')
            xlabel(app.UIAxes36, 'X')
            ylabel(app.UIAxes36, 'Y')
            app.UIAxes37 = uiaxes(app.SpatiulStarilorTab);
            title(app.UIAxes37, 'Spatiul starilor 4')
            xlabel(app.UIAxes37, 'X')
            ylabel(app.UIAxes37, 'Y')
            app.STARTButton_6 = uibutton(app.SpatiulStarilorTab, 'push');
            app.STARTButton_6.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_6Pushed, true);
            app.STARTButton_6.Text = 'START';
            app.MatriceadistantelorTab = uitab(app.TabGroup4);
            app.MatriceadistantelorTab.Title = 'Matricea distantelor';
            app.AfiseazamatriceadistantelorButton = uibutton(app.MatriceadistantelorTab, 'push');
            app.AfiseazamatriceadistantelorButton.ButtonPushedFcn = createCallbackFcn(app, @AfiseazamatriceadistantelorButtonPushed, true);
            app.AfiseazamatriceadistantelorButton.Text = 'Afiseaza matricea distantelor';
            app.UIAxes38 = uiaxes(app.MatriceadistantelorTab);
            title(app.UIAxes38, 'Matricea distantelor 1')
            xlabel(app.UIAxes38, 'Timp')
            ylabel(app.UIAxes38, 'Timp')
            app.UIAxes39 = uiaxes(app.MatriceadistantelorTab);
            title(app.UIAxes39, 'Matricea distantelor 2')
            xlabel(app.UIAxes39, 'Timp')
            ylabel(app.UIAxes39, 'Timp')
            app.UIAxes40 = uiaxes(app.MatriceadistantelorTab);
            title(app.UIAxes40, 'Matricea distantelor  3')
            xlabel(app.UIAxes40, 'X')
            ylabel(app.UIAxes40, 'Timp')
            app.UIAxes41 = uiaxes(app.MatriceadistantelorTab);
            title(app.UIAxes41, 'Matricea distantelor 4')
            xlabel(app.UIAxes41, 'Timp')
            ylabel(app.UIAxes41, 'Timp')
            app.DiagramarecurentelorTab = uitab(app.TabGroup4);
            app.DiagramarecurentelorTab.Title = 'Diagrama recurentelor';
            app.UIAxes42 = uiaxes(app.DiagramarecurentelorTab);
            title(app.UIAxes42, 'Diagrama recurentelor 1')
            xlabel(app.UIAxes42, 'Timp')
            ylabel(app.UIAxes42, 'Timp')
            app.UIAxes43 = uiaxes(app.DiagramarecurentelorTab);
            title(app.UIAxes43, 'Diagrama recurentelor 2')
            xlabel(app.UIAxes43, 'Timp')
            ylabel(app.UIAxes43, 'Timp')
            app.UIAxes44 = uiaxes(app.DiagramarecurentelorTab);
            title(app.UIAxes44, 'Diagrama recurentelor 3')
            xlabel(app.UIAxes44, 'Timp')
            ylabel(app.UIAxes44, 'Timp')
            app.UIAxes45 = uiaxes(app.DiagramarecurentelorTab);
            title(app.UIAxes45, 'Diagrama recurentelor 4')
            xlabel(app.UIAxes45, 'Timp')
            ylabel(app.UIAxes45, 'Timp')
            app.CurbelededetectieTab = uitab(app.TabGroup4);
            app.CurbelededetectieTab.Title = 'Curbele de detectie';
            app.UIAxes46 = uiaxes(app.CurbelededetectieTab);
            title(app.UIAxes46, 'Curba de detectie 1')
            xlabel(app.UIAxes46, 'Esantioane')
            ylabel(app.UIAxes46, 'Amplitudine [V]')
            app.UIAxes47 = uiaxes(app.CurbelededetectieTab);
            title(app.UIAxes47, 'Curba de detectie 3')
            xlabel(app.UIAxes47, 'Esantioane')
            ylabel(app.UIAxes47, 'Amplitudine [V]')
            app.UIAxes48 = uiaxes(app.CurbelededetectieTab);
            title(app.UIAxes48, 'Curba de detectie 2')
            xlabel(app.UIAxes48, 'Esantioane')
            ylabel(app.UIAxes48, 'Amplitudine [V]')
            app.UIAxes49 = uiaxes(app.CurbelededetectieTab);
            title(app.UIAxes49, 'Curba de detectie 4')
            xlabel(app.UIAxes49, 'Esantioane')
            ylabel(app.UIAxes49, 'Amplitudine [V]')
            app.UITable6 = uitable(app.CurbelededetectieTab);
            app.UITable6.ColumnName = {'TDOA12(sec)'; 'TDOA13 (sec)'; 'TDOA14 (sec)'};
            app.UITable6.RowName = {};
            app.TransformataWaveletTab = uitab(app.TabGroup);
            app.TransformataWaveletTab.Title = 'Transformata Wavelet';
            app.UITable3 = uitable(app.TransformataWaveletTab);
            app.UITable3.ColumnName = {'TDOA12 (sec)'; 'TDOA13  (sec)'; 'TDOA14  (sec)'};
            app.UITable3.RowName = {};
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.UIAxes17 = uiaxes(app.TransformataWaveletTab);
            title(app.UIAxes17, 'Curba de detectie 1')
            xlabel(app.UIAxes17, 'Esantioane')
            ylabel(app.UIAxes17, 'Amplitudine [V]')
            app.UIAxes18 = uiaxes(app.TransformataWaveletTab);
            title(app.UIAxes18, 'Curba de detectie 3')
            xlabel(app.UIAxes18, 'Esantioane')
            ylabel(app.UIAxes18, 'Amplitudine [V]')
            app.UIAxes19 = uiaxes(app.TransformataWaveletTab);
            title(app.UIAxes19, 'Curba de detectie 2')
            xlabel(app.UIAxes19, 'Esantioane')
            ylabel(app.UIAxes19, 'Amplitudine [V]')
            app.UIAxes20 = uiaxes(app.TransformataWaveletTab);
            title(app.UIAxes20, 'Curba de detectie 4')
            xlabel(app.UIAxes20, 'Esantioane')
            ylabel(app.UIAxes20, 'Amplitudine [V]')
            app.STARTButton_3 = uibutton(app.TransformataWaveletTab, 'push');
            app.STARTButton_3.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_3Pushed, true);
            app.STARTButton_3.Text = 'START';
            app.SpectrogramaTab = uitab(app.TabGroup);
            app.SpectrogramaTab.Title = 'Spectrograma';
            app.UITable4 = uitable(app.SpectrogramaTab);
            app.UITable4.ColumnName = {'TDOA12 (sec)'; 'TDOA13  (sec)'; 'TDOA14 (sec)'};
            app.UITable4.RowName = {};
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.UIAxes24 = uiaxes(app.SpectrogramaTab);
            title(app.UIAxes24, 'Curba de detectie 1')
            xlabel(app.UIAxes24, 'Esantioane')
            ylabel(app.UIAxes24, 'Amplitudine [V]')
            app.UIAxes25 = uiaxes(app.SpectrogramaTab);
            title(app.UIAxes25, 'Curba de detectie 3')
            xlabel(app.UIAxes25, 'Esantioane')
            ylabel(app.UIAxes25, 'Amplitudine [V]')
            app.UIAxes26 = uiaxes(app.SpectrogramaTab);
            title(app.UIAxes26, 'Curba de detectie 2')
            xlabel(app.UIAxes26, 'Esantioane')
            ylabel(app.UIAxes26, 'Amplitudine [V]')
            app.UIAxes27 = uiaxes(app.SpectrogramaTab);
            title(app.UIAxes27, 'Curba de detectie 4')
            xlabel(app.UIAxes27, 'Esantioane')
            ylabel(app.UIAxes27, 'Amplitudine [V]')
            app.STARTButton_7 = uibutton(app.SpectrogramaTab, 'push');
            app.STARTButton_7.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_4Pushed, true);
            app.STARTButton_7.Text = 'START';
            app.LocalizaresursaacusticaTab = uitab(app.TabGroup);
            app.LocalizaresursaacusticaTab.Title = 'Localizare sursa acustica';
            app.UITable5 = uitable(app.LocalizaresursaacusticaTab);
            app.UITable5.ColumnName = {'Axa x (m)'; 'Axa y (m)'; 'Axa z (m)'};
            app.UITable5.RowName = {};
            app.AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButton = uibutton(app.LocalizaresursaacusticaTab, 'push');
            app.AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButton.ButtonPushedFcn = createCallbackFcn(app, @AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButtonPushed, true);
            app.AfiseazagraficpozitiilemicrofoanelorsiasurseiacusticeButton.Text = 'Afiseaza grafic pozitiile microfoanelor si a sursei acustice';
            app.RezultateCrosscorelatiaButton = uibutton(app.LocalizaresursaacusticaTab, 'push');
            app.RezultateCrosscorelatiaButton.ButtonPushedFcn = createCallbackFcn(app, @RezultateCrosscorelatiaButtonPushed, true);
            app.RezultateCrosscorelatiaButton.Text = 'Rezultate Cross-corelatia';
            app.RezultateRPAButton = uibutton(app.LocalizaresursaacusticaTab, 'push');
            app.RezultateRPAButton.ButtonPushedFcn = createCallbackFcn(app, @RezultateRPAButtonPushed, true);
            app.RezultateRPAButton.Text = 'Rezultate RPA';
            app.RezultateTransformataWaveletButton = uibutton(app.LocalizaresursaacusticaTab, 'push');
            app.RezultateTransformataWaveletButton.ButtonPushedFcn = createCallbackFcn(app, @RezultateTransformataWaveletButtonPushed, true);
            app.RezultateTransformataWaveletButton.Text = 'Rezultate Transformata Wavelet';
            app.RezultateSpectrogramaButton = uibutton(app.LocalizaresursaacusticaTab, 'push');
            app.RezultateSpectrogramaButton.ButtonPushedFcn = createCallbackFcn(app, @RezultateSpectrogramaButtonPushed, true);
            app.RezultateSpectrogramaButton.Text = 'Rezultate Spectrograma';
            app.UITable7 = uitable(app.LocalizaresursaacusticaTab);
            app.UITable7.ColumnName = {'Axa x (m)'; 'Axa y (m)'; 'Axa z (m)'};
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.LocalizaresursaacusticaTab);
            app.UITable8.ColumnName = {'Axa x (m)'; 'Axa y (m)'; 'Axa z (m)'};
            app.UITable8.RowName = {};
            app.UITable9 = uitable(app.LocalizaresursaacusticaTab);
            app.UITable9.ColumnName = {'Axa x (m)'; 'Axa y (m)'; 'Axa z (m)'};
            app.UITable9.RowName = {};
            app.UITable12 = uitable(app.LocalizaresursaacusticaTab);
            app.UITable12.ColumnName = {'Axa x (m)'; 'Axa y (m)'; 'Axa z (m)'};
            app.UITable12.RowName = {};
            app.DetectiapraguluienergeticButton = uibutton(app.LocalizaresursaacusticaTab, 'push');
            app.DetectiapraguluienergeticButton.Text = 'Detectia pragului energetic';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sistem_de_detectie_si_localizare_acustica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end